classdef SignalPlotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ListBox                      matlab.ui.control.ListBox
        NoiseAmplitudeSlider_2       matlab.ui.control.Slider
        NoiseAmplitudeSlider_2Label  matlab.ui.control.Label
        DC_2                         matlab.ui.control.NumericEditField
        DCEditFieldLabel_2           matlab.ui.control.Label
        Frequency_2                  matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel_2  matlab.ui.control.Label
        Amplitude_2                  matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel_2    matlab.ui.control.Label
        SIGNAL2Label                 matlab.ui.control.Label
        SIGNAL1Label                 matlab.ui.control.Label
        NoiseAmplitudeSlider         matlab.ui.control.Slider
        NoiseAmplitudeSliderLabel    matlab.ui.control.Label
        DC                           matlab.ui.control.NumericEditField
        DCEditFieldLabel             matlab.ui.control.Label
        Frequency                    matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel    matlab.ui.control.Label
        Amplitude                    matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel      matlab.ui.control.Label
        Duration                     matlab.ui.control.NumericEditField
        DurationsEditFieldLabel      matlab.ui.control.Label
        PlotButton                   matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            k1 = app.DC.Value;
            a1 = app.Amplitude.Value;
            f1 = app.Frequency.Value;
            T1 = app.Duration.Value;
            n1 = app.NoiseAmplitudeSlider.Value;
            fs1 = 50*f1;
            Ts1 = 1/fs1;
            t1 = 0:Ts1:T1;
            ns1 = n1*randn(1,(length(t1)));
            x1 = k1+a1*cos(2*pi*f1*t1)+ns1;

            k2 = app.DC_2.Value;
            a2 = app.Amplitude_2.Value;
            f2 = app.Frequency_2.Value;
            n2 = app.NoiseAmplitudeSlider_2.Value;
            ns2 = n2*randn(1,(length(t1)));
            x2 = k2+a2*cos(2*pi*f2*t1)+ns2;

            switch app.ListBox.Value
                case 'Add'
                    x3 = x1+x2;
                case 'Subtract'
                    x3 = x1-x2;
                case 'Modulate'
                    x3 = x1.*x2;
                otherwise
                    x3 = zeros(size(x1));
            end;

            plot(app.UIAxes, t1,x1, 'g', t1, x2, 'r', t1, x3, 'b');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal Plot')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.DurationsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.Duration = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.Amplitude = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.Frequency = uieditfield(app.UIFigure, 'numeric');
            app.DCEditFieldLabel = uilabel(app.UIFigure);
            app.DCEditFieldLabel.Text = 'DC';
            app.DC = uieditfield(app.UIFigure, 'numeric');
            app.NoiseAmplitudeSliderLabel = uilabel(app.UIFigure);
            app.NoiseAmplitudeSliderLabel.Text = 'Noise Amplitude';
            app.NoiseAmplitudeSlider = uislider(app.UIFigure);
            app.NoiseAmplitudeSlider.Limits = [0 5];
            app.SIGNAL1Label = uilabel(app.UIFigure);
            app.SIGNAL1Label.Text = 'SIGNAL 1';
            app.SIGNAL2Label = uilabel(app.UIFigure);
            app.SIGNAL2Label.Text = 'SIGNAL 2';
            app.AmplitudeEditFieldLabel_2 = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel_2.Text = 'Amplitude';
            app.Amplitude_2 = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyHzEditFieldLabel_2 = uilabel(app.UIFigure);
            app.FrequencyHzEditFieldLabel_2.Text = 'Frequency (Hz)';
            app.Frequency_2 = uieditfield(app.UIFigure, 'numeric');
            app.DCEditFieldLabel_2 = uilabel(app.UIFigure);
            app.DCEditFieldLabel_2.Text = 'DC';
            app.DC_2 = uieditfield(app.UIFigure, 'numeric');
            app.NoiseAmplitudeSlider_2Label = uilabel(app.UIFigure);
            app.NoiseAmplitudeSlider_2Label.Text = 'Noise Amplitude';
            app.NoiseAmplitudeSlider_2 = uislider(app.UIFigure);
            app.NoiseAmplitudeSlider_2.Limits = [0 5];
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Add', 'Subtract', 'Modulate'};
            app.ListBox.Value = 'Add';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalPlotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end